classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ColumnsEditFieldLabel   matlab.ui.control.Label
        ColumnsEditField        matlab.ui.control.NumericEditField
        RowsEditFieldLabel      matlab.ui.control.Label
        RowsEditField           matlab.ui.control.NumericEditField
        UIAxes                  matlab.ui.control.UIAxes
        StepsEditFieldLabel     matlab.ui.control.Label
        StepsEditField          matlab.ui.control.NumericEditField
        StartButton             matlab.ui.control.Button
        StartingtabButtonGroup  matlab.ui.container.ButtonGroup
        immutableButton         matlab.ui.control.ToggleButton
        randomButton            matlab.ui.control.ToggleButton
        gliderButton            matlab.ui.control.ToggleButton
        oscillatorButton        matlab.ui.control.ToggleButton
        manualButton            matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        Property % Description
        tab;
    end
    
    methods (Access = private)
        
        function results = draw(app)
           map = [1 0 0 
               0 1 0];            
            I = imshow(app.tab, 'Parent', app.UIAxes, 'Colormap', map);
            %I = image(tab);
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        [sh1 sh2] = size(app.tab);    
        if sh1 > 0
        steps = app.StepsEditField.Value;
            while steps> 0
                pause(0.5);
                new_bound = periodicBounds(app.tab);
                app.tab = nextStepTab(new_bound);
                app.draw();
                steps = steps - 1;
            end
        end
        end

        % Selection changed function: StartingtabButtonGroup
        function StartingtabButtonGroupSelectionChanged(app, event)
            selectedButton = app.StartingtabButtonGroup.SelectedObject;
            rows = app.RowsEditField.Value;
            columns = app.ColumnsEditField.Value;
            if selectedButton == app.randomButton
                app.tab = createTab(4, rows, columns);
                app.draw()
            elseif selectedButton == app.immutableButton
                app.tab = createTab(1, rows, columns);                
                app.draw();
            elseif selectedButton == app.gliderButton
                app.tab = createTab(2, rows,columns);
                app.draw();
            elseif selectedButton == app.oscillatorButton
                app.tab = createTab(3, rows, columns);
                app.draw();
            elseif selectedButton == app.manualButton
                app.tab = createTab(5, rows, columns);
                app.draw();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnsEditField.Limits = [5 Inf];
            app.ColumnsEditField.Value = 20;
            app.RowsEditFieldLabel = uilabel(app.UIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowsEditField.Limits = [5 Inf];
            app.RowsEditField.Value = 20;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StepsEditFieldLabel = uilabel(app.UIFigure);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsEditField.Limits = [1 Inf];
            app.StepsEditField.Value = 20;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start!';
            app.StartingtabButtonGroup = uibuttongroup(app.UIFigure);
            app.StartingtabButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StartingtabButtonGroupSelectionChanged, true);
            app.StartingtabButtonGroup.Title = 'Starting tab';
            app.immutableButton = uitogglebutton(app.StartingtabButtonGroup);
            app.immutableButton.Text = 'immutable';
            app.immutableButton.Value = true;
            app.randomButton = uitogglebutton(app.StartingtabButtonGroup);
            app.randomButton.Text = 'random';
            app.gliderButton = uitogglebutton(app.StartingtabButtonGroup);
            app.gliderButton.Text = 'glider';
            app.oscillatorButton = uitogglebutton(app.StartingtabButtonGroup);
            app.oscillatorButton.Text = 'oscillator';
            app.manualButton = uitogglebutton(app.StartingtabButtonGroup);
            app.manualButton.Text = {'manual'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end